classdef gui_rename_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        CopyOutputButton      matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        M2J3RequirementLabel  matlab.ui.control.Label
        FORCEOUTPUT           matlab.ui.control.Label
        text4                 matlab.ui.control.Label
        loadedJoint           matlab.ui.control.EditField
        text3                 matlab.ui.control.Label
        text2                 matlab.ui.control.Label
        SaveTable             matlab.ui.control.Button
        slider1               matlab.ui.control.Slider
        posTable              matlab.ui.control.Table
        axes1                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        TENSION;
        NAMES;
        OUTPUT_TEXT;
        FORCE_OUTPUT = -30
    end
    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            % Set component properties that require runtime configuration
            app.posTable.BackgroundColor = [1 1 1];
            app.posTable.ColumnFormat = {[] [] [] []};
            app.posTable.RowName = cellstr(('A':'Z')');
            app.posTable.Data = cell(26, 4);
            %app.posTable.Position = [642 50 233 580];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function gui_rename_OpeningFcn(app, varargin)
            % --- Executes just before gui_rename is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app);
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   unrecognized PropertyName/PropertyValue pairs from the
            %            command line (see VARARGIN)
            
            % Choose default command line output for gui_rename
            handles.output = hObject;
            
            % Load table data on startup if file exists
                savePath = fullfile(fileparts(which('gui_rename.m')), 'processing', 'dim_table.mat');
                if isfile(savePath)
                    loaded = load(savePath);
                    existingData = loaded.tableData;
    
                    paddedData = cell(26, 4);
                    paddedData(1:size(existingData,1), :) = existingData;    
                    
                    app.posTable.Data = paddedData;
                    %app.posTable.Data = paddedData;
                    app.posTable.ColumnName = loaded.columnNames;
                    %set(handles.posTable, 'Data', loaded.tableData);
                    %set(handles.posTable, 'ColumnName', loaded.columnNames);
                end
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: SaveTable
        function SaveTable_Callback(app, event)
            % --- Executes on button press in SaveTable.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to SaveTable (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            tableData = get(handles.posTable,'Data');
            columnNames = get(handles.posTable,'ColumnName');
            jointToLoad = get(handles.loadedJoint,'String');
            letters = 'A':'Z';
            force = str2double(get(handles.FORCEOUTPUT,'String'));

            addpath(genpath('processing'));

            
            % save table to file
            savePath = fullfile(fileparts(mfilename('fullpath')),'processing', 'dim_table.mat');
            save(savePath, 'tableData', 'columnNames');
            
            msgbox(['Data saved to: ' savePath], 'Save Successful');
            
            
            bridgePlotter(handles.axes1,jointToLoad);
            drawnow;
            
            [app.TENSION,app.NAMES,Condition,app.OUTPUT_TEXT] = design_filer(force,find(letters==jointToLoad));
            disp(app.TENSION);
            set(handles.CONDITION,'String',sprintf(num2str(Condition)));

            numGauges = size(app.NAMES,1); 
            gaugePanel = uipanel(app.figure1, 'Units', 'pixels', 'Position', [0 0 900 200]);
            gaugeWidth = 40;
            gaugeHeight = 150;
            spacing = 10;
           
            for i = 1:numGauges
                %Calculate X position for each gauge
                xPos = (i-1) * (gaugeWidth + spacing) + 10;
    
    % IMPORTANT: Create the gauge directly as a child of the panel
    % If it still appears white, your version of MATLAB may require a 'uifigure'
                g = uigauge(gaugePanel, 'linear','Orientation', 'vertical','Position', [xPos, 50, gaugeWidth, gaugeHeight]);

                g.ScaleColors = {'blue', 'green', 'red'};
                g.Limits = [min(app.TENSION) max(app.TENSION)];
                %g.ScaleColorLimits = [0 30; 30 70; 70 100];
                g.Value = app.TENSION(i);

                lbl = uilabel(gaugePanel, 'Position', [xPos, 35, gaugeWidth, 20]);
                lbl.Text = sprintf('%s', app.NAMES(i));
                lbl.HorizontalAlignment = 'center';
            end
        end

        % Value changed function: slider1
        function slider1_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            %get sider scale
            p = get(handles.slider1,'Value');
            %calculated Force to apply and displays it
            weight = 30;
            FORCE = num2str(weight*p);
            %Result:%s Oz.',
            set(handles.FORCEOUTPUT,'String',sprintf(FORCE));
        end

        % Button pushed function: CopyOutputButton
        function copyButton(app, event)
            %get output on save
            textToCopy = app.OUTPUT_TEXT;
            %copy output to clipboard on save
            clipboard("copy",textToCopy);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'gui_rename';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.posTable = uitable(app.figure1);
            app.posTable.ColumnName = {'X1 POS'; 'Y1 POS'; 'X2 POS'; 'Y2 POS'};
            app.posTable.RowName = {'A'; 'B'; 'C'; 'D'; 'E'; 'F'; 'G'; 'H'; 'I'; 'J'; 'K'; 'L'; 'M'; 'N'; 'O'; 'P'; 'Q'; 'R'; 'S'; 'T'; 'U'; 'V'; 'W'; 'X'; 'Y'; 'Z'};
            app.posTable.ColumnEditable = [true true true true];
            app.posTable.Tag = 'posTable';
            app.slider1 = uislider(app.figure1);
            app.slider1.Limits = [0 2];
            app.slider1.MajorTicks = [];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2];
            app.slider1.Tag = 'slider1';
            app.slider1.Value = 1;
            app.SaveTable = uibutton(app.figure1, 'push');
            app.SaveTable.ButtonPushedFcn = createCallbackFcn(app, @SaveTable_Callback, true);
            app.SaveTable.Tag = 'SaveTable';
            app.SaveTable.Text = 'SaveTable';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Bridge Plot';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Applied Force Slider';
            app.loadedJoint = uieditfield(app.figure1, 'text');
            app.loadedJoint.Tag = 'loadedJoint';
            app.loadedJoint.Value = 'A';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Joint to Load';
            app.FORCEOUTPUT = uilabel(app.figure1);
            app.FORCEOUTPUT.Tag = 'FORCEOUTPUT';
            app.FORCEOUTPUT.Text = 'Static Text';
            app.M2J3RequirementLabel = uilabel(app.figure1);
            app.M2J3RequirementLabel.Interpreter = 'latex';
            app.M2J3RequirementLabel.Text = 'M = 2J-3 Requirement';
            app.Label = uilabel(app.figure1);
            app.Label.Tag = 'CONDITION';
            app.CopyOutputButton = uibutton(app.figure1, 'push');
            app.CopyOutputButton.ButtonPushedFcn = createCallbackFcn(app, @copyButton, true);
            app.CopyOutputButton.Text = 'Copy Output';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_rename_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)gui_rename_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end